classdef SsdotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        SaveSSDOTAsMenu             matlab.ui.container.Menu
        LoadSSDOTMenu               matlab.ui.container.Menu
        LogButton                   matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        GPUCheckBox                 matlab.ui.control.CheckBox
        ConditonDropDown            matlab.ui.control.DropDown
        ConditonDropDownLabel       matlab.ui.control.Label
        HRFCheckBox                 matlab.ui.control.CheckBox
        DataTree                    matlab.ui.container.Tree
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        SessButton                  matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        controller
    end
    methods (Access = private)
        function setupLayout(app)
            app.UIFigure.Position = [80 80 1100 700];
        
            rootGrid = uigridlayout(app.UIFigure, [1 2]);
            rootGrid.ColumnWidth = {250, '1x'};
            rootGrid.RowHeight = {'1x'};
            rootGrid.Padding = [10 10 10 10];
            rootGrid.ColumnSpacing = 10;
        
            sidePanel = uipanel(rootGrid);
            sidePanel.BorderType = 'none';
            sidePanel.Layout.Row = 1;
            sidePanel.Layout.Column = 1;
        

            sideGrid = uigridlayout(sidePanel, [8 1]);
            sideGrid.RowHeight = {'1x', '5x', '6x', '5x', '1.75x', '3x', '1.25x', '2x'};
            sideGrid.Padding = [5 5 5 5];
            sideGrid.RowSpacing = 5;

        
            app.BrowseData.Parent = sideGrid;
            app.BrowseData.Layout.Row  = 1;
            app.BrowseData.Layout.Column = 1;
        
            app.DataTree.Parent = sideGrid;
            app.DataTree.Layout.Row = 2;
        
            app.ProcessinglevelButtonGroup.Parent  = sideGrid;
            app.ProcessinglevelButtonGroup.Layout.Row = 3;
            app.ProcessinglevelButtonGroup.Layout.Column = 1;
        
            app.PlotTypeButtonGroup.Parent = sideGrid;
            app.PlotTypeButtonGroup.Layout.Row = 4;
            app.PlotTypeButtonGroup.Layout.Column = 1;
        
            chanGrid = uigridlayout(sideGrid, [1 2]);   
            chanGrid.ColumnWidth = {'fit', '1x'};
            chanGrid.Padding = [0 5 0 5];
            chanGrid.ColumnSpacing = 5;
            chanGrid.Layout.Row = 5;
            chanGrid.Layout.Column = 1;
        
            app.ChannelDropDownLabel.Parent = chanGrid;
            app.ChannelDropDownLabel.Layout.Row = 1;
            app.ChannelDropDownLabel.Layout.Column = 1;
        
            app.ChannelDropDown.Parent  = chanGrid;
            app.ChannelDropDown.Layout.Row    = 1;
            app.ChannelDropDown.Layout.Column = 2;
        
            app.SignalListBox.Parent        = sideGrid;
            app.SignalListBox.Layout.Row    = 6;
            app.SignalListBox.Layout.Column = 1;
        
            hrfGrid = uigridlayout(sideGrid, [1 3]);
            hrfGrid.ColumnWidth = {'fit', 'fit', '1x'};
            hrfGrid.Padding  = [0 5 0 5];
            hrfGrid.ColumnSpacing = 5;
            hrfGrid.Layout.Row = 7;
            hrfGrid.Layout.Column = 1;
        
            app.HRFCheckBox.Parent  = hrfGrid;
            app.HRFCheckBox.Layout.Row  = 1;
            app.HRFCheckBox.Layout.Column = 1;
        
            app.ConditonDropDownLabel.Parent = hrfGrid;
            app.ConditonDropDownLabel.Layout.Row  = 1;
            app.ConditonDropDownLabel.Layout.Column = 2;
        
            app.ConditonDropDown.Parent  = hrfGrid;
            app.ConditonDropDown.Layout.Row = 1;
            app.ConditonDropDown.Layout.Column = 3;
        
            btnGrid = uigridlayout(sideGrid, [1 2]);
            btnGrid.ColumnWidth   = {'1x', '1x'};
            btnGrid.Padding   = [0 5 0 5];
            btnGrid.ColumnSpacing = 5;
            btnGrid.Layout.Row  = 8;
            btnGrid.Layout.Column = 1;
        
            app.ProcessButton.Parent  = btnGrid;
            app.ProcessButton.Layout.Row  = 1;
            app.ProcessButton.Layout.Column = 1;
        
            app.PlotButton.Parent  = btnGrid;
            app.PlotButton.Layout.Row  = 1;
            app.PlotButton.Layout.Column = 2;
        
        
            plotPanel = uipanel(rootGrid);
            plotPanel.Title  = 'Plot';
            plotPanel.FontSize  = 13;
            plotPanel.Layout.Row = 1;
            plotPanel.Layout.Column = 2;
        
            plotGrid = uigridlayout(plotPanel, [1 1]);
            plotGrid.Padding = [5 5 5 5];
        
            app.UIAxes.Parent = plotGrid;
            app.UIAxes.Layout.Row = 1;
            app.UIAxes.Layout.Column = 1;
        end


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
    end
    methods (Access = public)
        function group = loadDataset(app, folder)
            group = app.controller.loadDataset(folder);
        end
    end
    methods (Access = private)
        function startupFcn(app)
            app.setupLayout();
            root = fileparts(fileparts(mfilename('fullpath')));
            addpath(fullfile(root,'src'));
            environment = ssdot.app.initializeEnvironment(root);
            app.controller = ssdot.app.AppController(app,environment);
        end
        function BrowseDataButtonPushed(app, event)
            app.controller.perform(@()app.controller.browseDataset());
        end
        function PlotTypeButtonGroupSelectionChanged(app, event)
            app.controller.updateSignalList();
        end
        function ProcessinglevelButtonGroupSelectionChanged(app, event)
            app.controller.changeProcessingLevel();
        end
        function PlotButtonPushed(app, event)
            app.controller.perform(@()app.controller.plotData());
        end
        function DataTreeSelectionChanged(app, event)
            app.controller.selectNode(event.SelectedNodes);
        end
        function HRFCheckBoxValueChanged(app, event)
            app.controller.updateSignalList();
        end
        function SignalListBoxClicked(app, event)
            % Selection is read when plotting.
        end
        function ProcessButtonPushed(app, event)
            app.controller.perform(@()app.controller.openPipeline());
        end
        function SaveSSDOTAsMenuSelected(app, event)
            app.controller.perform(@()app.controller.saveSelectedSession());
        end
        function GPUCheckBoxValueChanged(app, event)
            app.controller.perform(@()app.controller.setDevice(app.GPUCheckBox.Value));
        end
        function LogButtonPushed(app, event)
            app.controller.showLog();
        end
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
        function ButtonPushed(app, event)
            app.controller.perform(@()app.controller.openPipeline());
        end
    end
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveSSDOTAsMenu = uimenu(app.Menu);
            app.SaveSSDOTAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSSDOTAsMenuSelected, true);
            app.SaveSSDOTAsMenu.Text = 'Save SSDOT As';
            app.LoadSSDOTMenu = uimenu(app.Menu);
            app.LoadSSDOTMenu.Text = 'Load SSDOT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Load Homer derivitives';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessinglevelButtonGroupSelectionChanged, true);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            app.SessButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SessButton.Text = 'Sess';
            app.DataTree = uitree(app.UIFigure);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.HRFCheckBox = uicheckbox(app.UIFigure);
            app.HRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @HRFCheckBoxValueChanged, true);
            app.HRFCheckBox.Text = 'HRF';
            app.ConditonDropDownLabel = uilabel(app.UIFigure);
            app.ConditonDropDownLabel.Text = 'Conditon';
            app.ConditonDropDown = uidropdown(app.UIFigure);
            app.ConditonDropDown.Items = {};
            app.ConditonDropDown.Enable = 'off';
            app.ConditonDropDown.Value = {};
            app.GPUCheckBox = uicheckbox(app.UIFigure);
            app.GPUCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPUCheckBoxValueChanged, true);
            app.GPUCheckBox.Text = 'GPU';
            app.GPUCheckBox.Value = true;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'ssdot';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SsdotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)
            if ~isempty(app.controller) && isvalid(app.controller), delete(app.controller); end

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end